classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        EditField                      matlab.ui.control.EditField
        PortSelectorPanel              matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Arduino2Port                   matlab.ui.control.EditField
        Arduino2Portpins916Label       matlab.ui.control.Label
        Arduino1Port                   matlab.ui.control.EditField
        Arduino1PortLabel              matlab.ui.control.Label
        DictimporterPanel              matlab.ui.container.Panel
        UseDefaultDictionaryButton     matlab.ui.control.Button
        dict_name                      matlab.ui.control.EditField
        ImportDictionaryButton         matlab.ui.control.Button
        dict_table                     matlab.ui.control.Table
        BREAKButton                    matlab.ui.control.Button
        exel_name                      matlab.ui.control.EditField
        read_excel_button              matlab.ui.control.Button
        load_excel_button              matlab.ui.control.Button
        table                          matlab.ui.control.Table
        status_window                  matlab.ui.control.TextArea
        PowerPanel                     matlab.ui.container.Panel
        smu_switch                     matlab.ui.control.ToggleSwitch
        SMUSwitchLabel                 matlab.ui.control.Label
        box_switch                     matlab.ui.control.ToggleSwitch
        BOXSwitchLabel                 matlab.ui.control.Label
        box_lamp                       matlab.ui.control.Lamp
        smu_lamp                       matlab.ui.control.Lamp
        CenterPanel                    matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        CompliancebreakSwitch          matlab.ui.control.ToggleSwitch
        CompliancebreakSwitchLabel     matlab.ui.control.Label
        aperture                       matlab.ui.control.NumericEditField
        GScomplianceAEditFieldLabel_2  matlab.ui.control.Label
        vi_switch                      matlab.ui.control.Switch
        SweepvariableSwitchLabel       matlab.ui.control.Label
        curve_time                     matlab.ui.control.NumericEditField
        TimeofLastAcquisitionLabel     matlab.ui.control.Label
        dwellvoltage                   matlab.ui.control.NumericEditField
        DwellvoltageEditFieldLabel_2   matlab.ui.control.Label
        stepEditFieldLabel_2           matlab.ui.control.Label
        cycles                         matlab.ui.control.NumericEditField
        SweepdirectionButtonGroup      matlab.ui.container.ButtonGroup
        decrease_button                matlab.ui.control.RadioButton
        increase_button                matlab.ui.control.RadioButton
        step                           matlab.ui.control.NumericEditField
        stepEditFieldLabel             matlab.ui.control.Label
        dwell                          matlab.ui.control.NumericEditField
        DwelltimeEditFieldLabel        matlab.ui.control.Label
        n_points                       matlab.ui.control.NumericEditField
        numberpointsEditFieldLabel     matlab.ui.control.Label
        vds                            matlab.ui.control.NumericEditField
        vDSVEditFieldLabel             matlab.ui.control.Label
        vgs_max                        matlab.ui.control.NumericEditField
        vGSmaxVEditFieldLabel          matlab.ui.control.Label
        vgs_min                        matlab.ui.control.NumericEditField
        vGSminVEditFieldLabel          matlab.ui.control.Label
        gs_comp                        matlab.ui.control.NumericEditField
        GScomplianceAEditFieldLabel    matlab.ui.control.Label
        ds_comp                        matlab.ui.control.NumericEditField
        DScomplianceLabel              matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        add_resistance                 matlab.ui.control.CheckBox
        SelectpinpairLabel             matlab.ui.control.Label
        AddPins                        matlab.ui.control.CheckBox
        SHOWHIDEALLButton              matlab.ui.control.Button
        CHOOSEPATHButton               matlab.ui.control.Button
        save_path                      matlab.ui.control.EditField
        USENAMEASBACKUPButton          matlab.ui.control.Button
        UITablevi                      matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        X_axis                         matlab.ui.container.ButtonGroup
        X_axis_t                       matlab.ui.control.RadioButton
        X_axis_ids                     matlab.ui.control.RadioButton
        X_axis_vds                     matlab.ui.control.RadioButton
        X_axis_igs                     matlab.ui.control.RadioButton
        X_axis_vgs                     matlab.ui.control.RadioButton
        Y_axis                         matlab.ui.container.ButtonGroup
        Y_axis_t                       matlab.ui.control.RadioButton
        Y_axis_ids                     matlab.ui.control.RadioButton
        Y_axis_vds                     matlab.ui.control.RadioButton
        Y_axis_igs                     matlab.ui.control.RadioButton
        Y_axis_vgs                     matlab.ui.control.RadioButton
        delete_button                  matlab.ui.control.Button
        file_name                      matlab.ui.control.EditField
        pin1                           matlab.ui.control.EditField
        pin2                           matlab.ui.control.EditField
        hard_save_file_name            matlab.ui.control.EditField
        SAVEButton                     matlab.ui.control.Button
        curve_label                    matlab.ui.control.EditField
        DELETEHIDDENButton             matlab.ui.control.Button
        plot_button                    matlab.ui.control.Button
        UIAxes_vi                      matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        smu % global smu variable
        Ids % global ids vector
        Igs % global igs vector
        Vds % global vds vector
        Vgs % global vgs vector
        t   % global t vector

        % Experimental
        Vds_expected
        Vgs_expected


        data_table % global data table
        data_table_vi % global data table for vi curve (?)

        % arduinos
        a1
        a2

        pin_relay_map % relay map


        colors % colors array
        color_idx

        cells_to_hide

        hide_show_but_flag


    end

    properties (Access = public)
        interrupt_flag % to interrupt the program

    end

    methods (Access = private)

        function hide_cells(app, cell_array)
            for cell=cell_array
                cell.Enable = "off";
            end
        end

        function show_cells(app,cell_array)
            for cell=cell_array
                cell.Enable = "on";
            end

        end

        function get_gfet_curve(app)
            %% see in which mode we are (dummy, smu only, or box)
            mode = get_mode(app);

            %% get curve id
            Curve_id = get_curve_id(app);
            if app.AddPins.Value ==1
                Curve_id = Curve_id + "(" + app.pin1.Value + ", " + app.pin2.Value + ")";
            end

            %% add curve id to table
            addCurveToTable(app, app.UITable, string(Curve_id), app.colors(app.color_idx,:));

            %% aquire all vars
            DS_comp = app.ds_comp.Value;
            GS_comp = app.gs_comp.Value;
            Aperture = app.aperture.Value;
            Vgs_min = app.vgs_min.Value;
            Vgs_max = app.vgs_max.Value;
            vDS = app.vds.Value;
            N_points = app.n_points.Value;
            Dwell_time = app.dwell.Value;
            Dwell_voltage = app.dwellvoltage.Value;
            n_cycles = app.cycles.Value;


            %% get plot type
            xvar = app.X_axis.SelectedObject.Text;
            yvar = app.Y_axis.SelectedObject.Text;

            %% defines vgs and vds array
            Vgs_list = get_sweeping_array(app, Vgs_min, Vgs_max, N_points);


            if app.SweepdirectionButtonGroup.SelectedObject.Text == "Decrease"
                Vgs_list = flip(Vgs_list);
            end
            %% begin curve aquisition
            if mode=="dummy"
                [app.Ids,app.Vds,app.Igs,app.Vgs,app.t]= dummy_smu_sweep(app,app.vds.Value,Vgs_list,Dwell_time, app.UIAxes,string(Curve_id), xvar,yvar,app.colors(app.color_idx,:));
                log_message(app.status_window,"DUMMY MODE")
            else
                % defines chanels
                fixed_chanel = 1;
                sweep_chanel = 2;


                if mode =="box"
                    % load the map
                    Dict_table = cell2table(app.dict_table.Data, 'VariableNames', {'Chip', 'box'});
                    dict = dictionary(Dict_table{:,1}, Dict_table{:,2});
                    RELAY_PairOn(str2double(app.pin1.Value),str2double(app.pin2.Value),app.pin_relay_map,dict);
                end
                % dwell operations
                %smu_dc_voltage2(app.smu, fixed_chanel, vDS,DS_comp)
                %smu_dc_voltage2(app.smu, sweep_chanel, Dwell_voltage,GS_comp)
                smu_dc_state(app.smu,1, 1)
                smu_dc_state(app.smu,2, 1)
                pause(Dwell_time)
                [app.Ids,app.Vds, app.Vds_expected, app.Igs,app.Vgs, app.Vgs_expected, app.t] = smu_internal_sweep3(app.smu, Vgs_list,vDS, DS_comp, GS_comp, ...
                    app.UIAxes, string(Curve_id), app.colors(app.color_idx,:),xvar,yvar, fixed_chanel, Aperture);

                if mode == "box"
                    % open relay curve
                    RELAY_PairOff(string(app.pin1.Value),string(app.pin2.Value),app.pin_relay_map,dict);
                end

                smu_dc_state(app.smu,fixed_chanel,0)
                smu_dc_state(app.smu,sweep_chanel,0)
            end
            app.curve_time.Value = app.t(end);

            %% check if compliance was ever hit
            manage_compliances(app, app.Ids, DS_comp, app.Igs, GS_comp, Curve_id)
            %% log message
            log_message(app.status_window,"Curve aquired!")

            %% safety saving

            File_name = app.file_name.Value;
            path =  app.save_path.Value + "\Data\Safety_Save";
            if ~exist(path, 'dir')
                mkdir(path);
            end
            %disp(app.cycles.Value)
            safety_save(path, File_name, Curve_id,...
                DS_comp, GS_comp, Aperture, Vgs_min, Vgs_max, vDS, N_points, Dwell_time, Dwell_voltage, n_cycles,...
                app.Ids, app.Vds, app.Vds_expected, app.Igs, app.Vgs, app.Vgs_expected, app.t)


            %% hard save
            app.data_table = hard_save(app.data_table, Curve_id,...
                DS_comp, GS_comp, Aperture, Vgs_min, Vgs_max, vDS, N_points, Dwell_time, Dwell_voltage,n_cycles,...
                app.Ids, app.Vds, app.Vds_expected, app.Igs, app.Vgs, app.Vgs_expected, app.t);

            %             %% show cells
            %             show_cells(app,cell_array)
            app.color_idx = app.color_idx+1;
        end

        function mode = get_mode(app)

            if app.smu_switch.Value == "On" && app.box_switch.Value == "On"
                mode= "box";
            elseif app.smu_switch.Value == "On" && app.box_switch.Value == "Off"
                mode= "smu_only";
            elseif app.smu_switch.Value == "Off" && app.box_switch.Value == "Off"
                mode= "dummy";
            end
        end

        function Curve_id = get_curve_id(app)
            % reads given id and see if it already exists; if not then
            % change it

            if app.vi_switch.Value == "vDS"
                [Curve_id, unique] = check_label(app.curve_label.Value,get_table_data(app,app.UITablevi));

            else
                [Curve_id, unique] = check_label(app.curve_label.Value,get_table_data(app,app.UITable));

            end

            if unique==0
                log_message(app.status_window, app.curve_label.Value + " already exists! Replaced by "+ Curve_id+".");
            end

            app.curve_label.Value = Curve_id;

        end

        function array = get_sweeping_array(app,min,max,npoints)
            if app.cycles.Value ==0
                array = linspace(min, max,npoints);
            else
                array_ = linspace(min, max,npoints);
                full_cycle = [array_, flip(array_(2:end-1))];
                array = repmat(full_cycle, 1, app.cycles.Value);
            end
        end


        function get_vi_curve (app)
            %% see in which mode we are (dummy, smu only, or box)
            mode = get_mode(app);

            %% get curve id
            Curve_id = get_curve_id(app);
            if app.AddPins.Value ==1
                Curve_id = Curve_id + "(" + app.pin1.Value + ", " + app.pin2.Value + ")";
            end

            %% add curve id to table
            addCurveToTable(app, app.UITablevi, string(Curve_id), app.colors(app.color_idx,:));

            %% aquire all vars
            GS_comp = app.gs_comp.Value;
            DS_comp = app.ds_comp.Value;
            Aperture = app.aperture.Value;

            % reusing the vgs max and min boxes
            Vds_min = app.vgs_min.Value;
            Vds_max = app.vgs_max.Value;
            vGS = app.vds.Value;
            N_points = app.n_points.Value;
            Dwell_time = app.dwell.Value;
            Dwell_voltage = app.dwellvoltage.Value;
            n_cycles = app.cycles.Value;

            %% get plot type
            xvar = app.X_axis.SelectedObject.Text;
            yvar = app.Y_axis.SelectedObject.Text;

            %% defines vds array
            Vds_list = get_sweeping_array(app, Vds_min, Vds_max, N_points);


            if app.SweepdirectionButtonGroup.SelectedObject.Text == "Decrease"
                Vds_list = flip(Vds_list);
            end

            %% begin curve aquisition
            if mode=="dummy"
                [app.Ids,app.Vds,app.Igs,app.Vgs,app.t]= dummy_smu_sweep(app,vGS, Vds_list,Dwell_time, app.UIAxes_vi ,string(Curve_id), xvar,yvar,app.colors(app.color_idx,:));
                log_message(app.status_window,"DUMMY MODE")
            else

                % defines chanels
                fixed_chanel = 2;
                sweep_chanel = 1;


                if mode =="box"
                    % load the map
                    Dict_table = cell2table(app.dict_table.Data, 'VariableNames', {'Chip', 'box'});
                    dict = dictionary(Dict_table{:,1}, Dict_table{:,2});
                    % close relay pair
                    RELAY_PairOn(string(app.pin1.Value),string(app.pin2.Value),app.pin_relay_map,dict);
                end

                % dwell operations
                smu_dc_voltage2(app.smu, fixed_chanel, vGS,GS_comp)
                smu_dc_voltage2(app.smu, sweep_chanel, Dwell_voltage,DS_comp)
                smu_dc_state(app.smu,1, 1)
                smu_dc_state(app.smu,2, 1)
                pause(Dwell_time)

                % aquire curve


                [app.Igs,app.Vds, app.Vds_expected, app.Ids,app.Vgs, app.Vgs_expected, app.t] = smu_internal_sweep3(app.smu, Vds_list,vGS, DS_comp, GS_comp, ...
                    app.UIAxes_vi, string(Curve_id), app.colors(app.color_idx,:),xvar,yvar, fixed_chanel,Aperture);


                if mode == "box"
                    % open relay curve
                    RELAY_PairOff(string(app.pin1.Value),string(app.pin2.Value),app.pin_relay_map,dict);
                end


                % closes smu channels
                smu_dc_state(app.smu,fixed_chanel,0)
                smu_dc_state(app.smu,sweep_chanel,0)
            end
            app.curve_time.Value = app.t(end);

            %% update label if resistance ticked
            if app.add_resistance.Value == 1
                resistance = calculate_resistance(app.Vds, app.Ids);
                lastRow = size(app.UITablevi.Data, 1);

                % Append resistance value to existing label
                currentLabel = app.UITablevi.Data{lastRow, 1};
                updatedLabel = sprintf('%s (%.2f Ω)', currentLabel, resistance);

                app.UITablevi.Data{lastRow, 1} = updatedLabel;

                curves = findall(app.UIAxes_vi, 'DisplayName', string(Curve_id));

                for i = 1:length(curves)
                    curves(i).DisplayName = updatedLabel;
                end

            end


            %% log message
            log_message(app.status_window,"Curve aquired!")

            %% safety saving
            File_name = app.file_name.Value + "VI";
            path =  app.save_path.Value + "\Data\Safety_Save";
            if ~exist(path, 'dir')
                mkdir(path);
            end
            %safety_save(path, File_name, Curve_id,DS_comp, GS_comp, Aperture, Vds_min, Vds_max, vGS, N_points, Dwell_time,app.Ids, app.Vds, app.Igs, app.Vgs, app.t)
            safety_save(path, File_name, Curve_id,...
                DS_comp, GS_comp, Aperture,Vds_min, Vds_max, vGS, N_points, Dwell_time, Dwell_voltage, n_cycles,...
                app.Ids, app.Vds, app.Vds_expected, app.Igs, app.Vgs, app.Vgs_expected, app.t)
            %% hard save
            app.data_table_vi = hard_save(app.data_table_vi, Curve_id,...
                DS_comp, GS_comp, Aperture,Vds_min, Vds_max, vGS, N_points, Dwell_time, Dwell_voltage,n_cycles,...
                app.Ids, app.Vds, app.Vds_expected, app.Igs, app.Vgs, app.Vgs_expected, app.t);
            %hard_save(app.data_table_vi, Curve_id,DS_comp, GS_comp, Aperture, Vds_min, Vds_max, vGS, N_points, Dwell_time,app.Ids, app.Vds, app.Igs, app.Vgs, app.t);
            app.color_idx = app.color_idx+1;


        end


        function update_axes(app)
            %% Get VI mode
            vi_mode = app.vi_switch.Value;

            if vi_mode == "vDS"
                Ax = app.UIAxes_vi;
                data = app.data_table_vi;
            else
                Ax = app.UIAxes;
                data = app.data_table;
            end

            % **Extract existing colors before clearing the axes**
            existing_lines = findobj(Ax, 'Type', 'Line'); % Find all plotted lines

            colors_map = containers.Map; % Create a dictionary to store colors

            for i = 1:length(existing_lines)
                label = existing_lines(i).DisplayName; % Get the curve label
                if ~isempty(label) % Ensure the label exists
                    colors_map(label) = existing_lines(i).Color; % Store color
                end
            end

            cla(Ax) % Now clear the axes

            y_button = app.Y_axis.SelectedObject.Text;
            x_button = app.X_axis.SelectedObject.Text;


            try % opa o gajo tripa se mudarmos os eixos antes de termos algum plot, para ja fica assim
                for i = 1:size(data,1)
                    label = string(data{i,"Curve_id"});
                    x = data{i,x_button}{1};
                    y = data{i,y_button}{1};

                    % **Reuse color if it exists, otherwise use default**
                    if isKey(colors_map, label)
                        plot(Ax, x, y, DisplayName=label, Color=colors_map(label),LineWidth= 1.5);

                    end
                end
            catch
                uialert(app.UIFigure, "Something sus happened!", 'Connection Error', 'Icon', 'error');
            end
            xlabel(Ax, x_button);
            ylabel(Ax, y_button);

        end

        function addCurveToTable(app, table,curveName, curveColor)


            % Get the current table data
            currentData = table.Data;

            % Append the new row

            if isempty(currentData)
                table.Data = {char(curveName),char(" ") };
            else
                table.Data = [currentData; {char(curveName), char(" ") }]; % Append the new row
            end
            % Apply the style to the newly added row
            numRows = size(table.Data, 1); % Total number of rows
            style = uistyle('BackgroundColor', curveColor); % Create style with specified color
            addStyle(table, style, 'cell', [numRows, 2]); % Apply the style to the last row

        end

        function table_data = get_table_data(app, table)

            [~,c] = size(table.Data);

            if c ==0
                table_data = "none";
            else
                table_data = table.Data(:, 1);
            end

        end

        function hide_show_curves(app,table,ax, visibility)

            data = get_table_data(app, table);

            for i = 1:size(data)
                selected_label = data{i};

                % Find the line object with a specific DisplayName in the axes
                curveToToggle = findobj(ax, 'Type', 'Line', 'DisplayName', selected_label);

                % Check if the curve exists
                if ~isempty(curveToToggle)
                    set(curveToToggle, 'Visible', visibility);
                end

                if visibility =="off"
                    addStyle(table, uistyle('FontColor', [0.5 0.5 0.5]) );
                else
                    addStyle(table, uistyle('FontColor', [0 0 0] ));
                end

            end
        end

        function  manage_compliances(app, I1, I1comp, I2, I2comp, Curve_id) %% i suck at naming functions
            channel1compliance = check_compliance(I1, I1comp);
            channel2compliance = check_compliance(I2, I2comp);

            %% stop program
            compliance_break =app.CompliancebreakSwitch.Value;
            %% run program but add compliance to the label

            if channel1compliance==1
                log_message(app.status_window,"Channel 1 compliance was hit!")

                if method==1
                    uialert(app.UIFigure, "Channel 1 compliance hit! Measurements interrupted.", 'Compliance Warning', 'Icon', 'error');
                    lastRow = size(app.UITable.Data, 1);
                currentLabel = app.UITable.Data{lastRow, 1};
                updatedLabel = [currentLabel, ' COMPLIANCE'];
                app.UITable.Data{lastRow, 1} = updatedLabel;
                curves = findall(app.UIAxes, 'DisplayName', string(Curve_id));
                for i = 1:length(curves)
                    curves(i).DisplayName = updatedLabel;
                end

                if compliance_break=="On"
                    uialert(app.UIFigure, "Channel 1 compliance hit! Measurements interrupted.", 'Compliance Warning', 'Icon', 'error');
                    BREAKButtonPushed(app, [])
                end

                end
            end

            if channel2compliance==1
                log_message(app.status_window,"Channel 2 compliance was hit!")

                lastRow = size(app.UITable.Data, 1);
                currentLabel = app.UITable.Data{lastRow, 1};
                updatedLabel = [currentLabel, ' COMPLIANCE'];
                app.UITable.Data{lastRow, 1} = updatedLabel;
                curves = findall(app.UIAxes, 'DisplayName', string(Curve_id));
                for i = 1:length(curves)
                    curves(i).DisplayName = updatedLabel;
                end
                if compliance_break=="On"
                    uialert(app.UIFigure, "Channel 2 compliance hit!", 'Compliance Warning', 'Icon', 'error');
                    BREAKButtonPushed(app, [])
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data_table=nan; % initiate table
            app.data_table_vi = nan;

            %sets step (to remove in final version)
            npoints = app.n_points.Value;
            vmin = app.vgs_min.Value;
            vmax = app.vgs_max.Value;
            app.step.Value = (vmax-vmin)/npoints;

            % show/hide the different axis

            if app.vi_switch.Value == "vDS"
                app.UIAxes.Visible = "Off";
                app.UIAxes_vi.Visible = "On";
                app.UITablevi.Visible = "on";
                app.UITable.Visible = "off";
            else
                app.UIAxes.Visible = "On";
                app.UIAxes_vi.Visible = "Off";
                app.UITable.Visible = "on";
                app.UITablevi.Visible = "off";
            end

            app.colors = rand(1000, 3); % Each row is an RGB triplet

            app.color_idx = 1;

            app.interrupt_flag = 0;

            app.hide_show_but_flag = 0;

            app.cells_to_hide = [app.Y_axis, app.X_axis, app.vi_switch, app.load_excel_button,app.smu_switch, app.box_switch, app.SAVEButton, app.delete_button, app.plot_button];


            % add path
            addpath(fullfile(fileparts(mfilename('fullpath')),"functions"))

            % select data folder
            app.save_path.Value = fullfile(fileparts(cd), 'Data');


            %% visivility
            app.read_excel_button.Enable = "off";
        end

        % Button pushed function: load_excel_button
        function load_excel_buttonButtonPushed(app, event)
            % Load table data from Excel file
            log_message(app.status_window, "Loading table...")
            fileName = uigetfile( ...
                {'*.csv;*.xls;*.xlsx', 'CSV and Excel Files (*.csv, *.xls, *.xlsx)'}, ...
                'Select a Data File',fileparts(mfilename('fullpath')));

            % forces the app to keep on top
            drawnow;figure(app.UIFigure);

            if fileName==0
                log_message(app.status_window, "No file selected!")
                return;
            end

            % load file as table
            Table = load_table(fileName);
            %             Table.pin_1= char(Table.pin_1);
            %             Table.pin_2= char(Table.pin_2);

            log_message(app.status_window, "Loaded " + fileName +" !")
            app.exel_name.Value = fileName;

            %% visibility
            app.read_excel_button.Enable = "on";

            %%


            % Assign table data and column names to UI Table
            app.table.Data = table2cell(Table); % Convert table to cell array for display
            app.table.ColumnName = Table.Properties.VariableNames; % Extract column names
        end

        % Value changed function: smu_switch
        function smu_switchValueChanged(app, event)
            value = app.smu_switch.Value;
            if value =="On"
                app.smu_lamp.Color= "yellow";
                log_message(app.status_window,"Connecting to SMU...")
                try
                    app.smu = smu_connect();
                    log_message(app.status_window,"SMU connected!")
                    app.smu_lamp.Color= "green";
                catch
                    uialert(app.UIFigure, "Couldn't connect to SMU!", 'Connection Error', 'Icon', 'error');
                    %log_message(app.status_window, "ERROR: " + ME.message);
                    app.smu_lamp.Color= "#e6e6e6";
                    app.smu_switch.Value="Off";
                end
            end

            if value =="Off"
                app.smu_lamp.Color= "yellow";
                log_message(app.status_window,"Disconnecting SMU...")
                try
                    app.smu = smu_disconnect(app.smu);
                    log_message(app.status_window,"SMU dicconnected!")
                    app.smu_lamp.Color= "#e6e6e6";
                    app.smu = [];
                catch ME
                    uialert(app.UIFigure, "Couldn't disconnect to SMU!", 'Connection Error', 'Icon', 'error');
                    log_message(app.status_window, "ERROR: " + ME.message);
                    app.smu_lamp.Color= "green";
                    app.smu_switch.Value="On";
                end
            end


        end

        % Button pushed function: plot_button
        function plot_buttonButtonPushed(app, event)
            app.interrupt_flag =0;% esta linha pode ou nao breaker isto tudo
            %% data_save_checks

            if isempty(app.save_path.Value)
                uialert(app.UIFigure, ...
                    'Save path is empty. Please select a valid folder.', ...
                    'Missing Save Path', ...
                    'Icon', 'warning');
                return;
            end

            if ~isfolder(app.save_path.Value)
                uialert(app.UIFigure, ...
                    'The selected data folder does not exist.', ...
                    'Invalid Save Path', ...
                    'Icon', 'warning');
                return;
            end


            %% is there a dict loaded?
            if isempty(app.dict_table.Data)==1
                choice = questdlg(['A loaded dictionary is required! Do you wish to load a default dictionary?'],...
                    'Dictionary Missing', ...
                    'OK','Cancel','Cancel');

                if strcmp(choice, 'OK')
                    UseDefaultDictionaryButtonPushed(app, event)

                else
                    return;  % or do something else, like stop processing
                end
            end


            %% are the selected pins in the dict?
            dic_data = app.dict_table.Data;
            [linhas, ~] = size(dic_data);
            dic_array = [];
            for linha = 1:linhas
                dic_array(linha) = dic_data{linha,1};
            end


            if ~ismember(str2double(app.pin1.Value), dic_array)

                uialert(app.UIFigure, "Pin 1 was not found on the loaded dictionary! Operation aborted!", 'Discrepancy detected', 'Icon', 'error');
                log_message(app.status_window,"Operation interrupted! Pin 1 not found in dictionary!")
                app.interrupt_flag =1;

                show_cells(app,app.cells_to_hide)
                return;
            elseif  ~ismember(str2double(app.pin2.Value), dic_array)

                uialert(app.UIFigure, "Pin 2 was not found on the loaded dictionary! Operation aborted!", 'Discrepancy detected', 'Icon', 'error');
                log_message(app.status_window,"Operation interrupted! Pin 1 not found in dictionary!")
                app.interrupt_flag =1;

                show_cells(app,app.cells_to_hide)
                return;
            end

            %% are the pins the same?
            if str2double(app.pin1.Value) == str2double(app.pin2.Value)
                uialert(app.UIFigure, "Pins 1 and 2 need to be different! Operation aborted!", 'Discrepancy detected', 'Icon', 'error');
                log_message(app.status_window,"Operation interrupted! Equal pins!")
                return;
            end

            hide_cells(app,app.cells_to_hide)

            if app.read_excel_button.Enable=="on"
                but_flag = 1;
            else
                but_flag = 0;
            end

            hide_cells(app,app.read_excel_button)
            %% what curve type is?
            if app.vi_switch.Value=="vGS"
                get_gfet_curve(app)
            else
                get_vi_curve(app)
            end

            show_cells(app,app.cells_to_hide)
            if but_flag==1
                show_cells(app,app.read_excel_button)
            end

        end

        % Button pushed function: DELETEHIDDENButton
        function DELETEHIDDENButtonPushed(app, event)
            if app.vi_switch.Value == "vDS"
                app.data_table = delete_curves(app.UIAxes_vi, app.data_table_vi, app.UITablevi);
            else
                app.data_table = delete_curves(app.UIAxes, app.data_table, app.UITable);

            end


        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            path =  app.save_path.Value + "\Data\Hard_Save";
            if ~exist(path, 'dir')
                mkdir(path);
            end
            File_name = app.hard_save_file_name.Value;

            if app.vi_switch.Value =="vDS"
                File_name = File_name + "_VI";
                save_data(app.UIAxes_vi, app.data_table_vi, path+"\"+File_name)
            else
                save_data(app.UIAxes, app.data_table, path+"\"+File_name)
            end


        end

        % Value changed function: box_switch
        function box_switchValueChanged(app, event)
            value = app.box_switch.Value;

            if value =="On"
                app.box_lamp.Color= "yellow";
                log_message(app.status_window,"Connecting to box...")
                try
                    [app.a1, app.a2] = box_connect(app.Arduino1Port.Value,app.Arduino2Port.Value);

                    % creates map!

                    pin = ["1+", "1-", "2+", "2-", "3+", "3-", "4+", "4-", "5+", "5-", "6+", "6-", "7+", "7-", "8+", "8-", ...
                        "9+", "9-", "10+", "10-", "11+", "11-", "12+", "12-", "13+", "13-", "14+", "14-", "15+", "15-", "16+", "16-"];

                    relay = { {app.a1, "8"}, {app.a1, "9"}, {app.a1, "7"}, {app.a1, "10"}, {app.a1, "6"}, {app.a1, "11"}, {app.a1, "5"}, {app.a1, "12"}, {app.a1, "4"}, {app.a1, "13"}, {app.a1, "3"}, {app.a1, "14"}, {app.a1, "2"}, {app.a1, "15"}, {app.a1, "1"}, {app.a1, "16"}, ...
                        {app.a2, "8"}, {app.a2, "9"}, {app.a2, "7"}, {app.a2, "10"}, {app.a2, "6"}, {app.a2, "11"}, {app.a2, "5"}, {app.a2, "12"}, {app.a2, "4"}, {app.a2, "13"}, {app.a2, "3"}, {app.a2, "14"}, {app.a2, "2"}, {app.a2, "15"}, {app.a2, "1"}, {app.a2, "16"}};

                    % Create a containers.Map object
                    app.pin_relay_map = containers.Map();
                    for i = 1:length(pin)
                        app.pin_relay_map(pin{i}) = relay{i};
                    end

                    log_message(app.status_window,"Box connected!")
                    app.box_lamp.Color= "green";
                catch ME
                    uialert(app.UIFigure, "Couldn't connect to box!", 'Connection Error', 'Icon', 'error');
                    log_message(app.status_window, "ERROR: " + ME.message);
                    app.box_lamp.Color= "#e6e6e6";
                    app.box_switch.Value="Off";
                end
            end

            if value =="Off"
                app.box_lamp.Color= "yellow";
                log_message(app.status_window,"Disconnecting box...")
                try
                    app.a1 = [];
                    app.a2 = [];
                    log_message(app.status_window,"Box dicconnected!")
                    app.box_lamp.Color= "#e6e6e6";

                catch ME
                    uialert(app.UIFigure, "Couldn't disconnect to box!", 'Connection Error', 'Icon', 'error');
                    log_message(app.status_window, "ERROR: " + ME.message);
                    app.box_lamp.Color= "green";
                    app.box_switch.Value="On";
                end
            end


        end

        % Value changed function: n_points
        function n_pointsValueChanged(app, event)
            npoints = app.n_points.Value;
            vmin = app.vgs_min.Value;
            vmax = app.vgs_max.Value;
            app.step.Value = (vmax-vmin)/npoints;
        end

        % Value changed function: step
        function stepValueChanged(app, event)
            Step = app.step.Value;
            vmin = app.vgs_min.Value;
            vmax = app.vgs_max.Value;
            app.n_points.Value = (vmax-vmin)/Step;


        end

        % Button pushed function: delete_button
        function delete_buttonPushed(app, event)
            if app.vi_switch.Value == "vDS"

                label_data = get_table_data(app, app.UITablevi);


                for i = 1:size(label_data)
                    selected_label = label_data{i};


                    % Find the line object with a specific DisplayName in the axes
                    curveToToggle = findobj(app.UIAxes_vi, 'Type', 'Line', 'DisplayName', selected_label);


                    % Check if the curve exists
                    if ~isempty(curveToToggle)
                        % Get the current visibility state
                        currentVisibility = get(curveToToggle, 'Visible');

                        % Toggle visibility
                        if strcmp(currentVisibility, 'on')
                            set(curveToToggle, 'Visible', 'off');

                        end
                    end
                end


                app.data_table = delete_curves(app.UIAxes_vi, app.data_table_vi, app.UITablevi);
            else


                label_data = get_table_data(app, app.UITable);

                for i = 1:size(label_data)
                    selected_label = label_data{i};

                    % Find the line object with a specific DisplayName in the axes
                    curveToToggle = findobj(app.UIAxes, 'Type', 'Line', 'DisplayName', selected_label);

                    % Check if the curve exists
                    if ~isempty(curveToToggle)
                        % Get the current visibility state
                        currentVisibility = get(curveToToggle, 'Visible');

                        % Toggle visibility
                        if strcmp(currentVisibility, 'on')
                            set(curveToToggle, 'Visible', 'off');

                        end
                    end
                end

                app.data_table = delete_curves(app.UIAxes, app.data_table, app.UITable);

            end
        end

        % Button pushed function: read_excel_button
        function read_excel_buttonButtonPushed(app, event)
            %% check if right excell was loaded
            varible_names = app.table.ColumnName;
            for i =1:size(varible_names,1)
                var = varible_names{i,1};
                if var == "vGS_min(V)" || var=="vGS_max(V)" || var== "vDS(V)"
                    excel_type = "transfer_curve";
                    break;
                elseif  var == "vDS_min(V)" || var=="vDS_max(V)" || var== "vGS(V)"
                    excel_type = "VI";
                    break;
                else
                    excel_type = "wrong";
                end

            end

            error_flag = 0;
            if excel_type == "transfer_curve" && app.vi_switch.Value =="vDS"
                error_flag =1;
            end

            if excel_type == "VI" && app.vi_switch.Value =="vGS"
                error_flag =2;
            end

            if excel_type =="wrong"
                uialert(app.UIFigure, "The loaded excel does not appear to correspond to a curve aquisition file, please change it!", 'Discrepancy detected', 'Icon', 'error');
                error_flag = 3;
            end
            if error_flag ==1
                uialert(app.UIFigure, "The loaded excel appears to correspond to a typical GFET curve, while the current mode is set to VI monitorization!", 'Discrepancy detected', 'Icon', 'error');
            end

            if error_flag==2
                uialert(app.UIFigure, "The loaded excel appears to correspond to a VI curve, while the current mode is set to a typical GFET curve!", 'Discrepancy detected', 'Icon', 'error');
            end


            if error_flag ==0
                hide_cells(app,app.read_excel_button)

                %% check if there is any key not present in the loaded dictionary

                dic_data = app.dict_table.Data;
                [linhas, ~] = size(dic_data);
                dic_array = [];
                for linha = 1:linhas
                    dic_array(linha) = dic_data{linha,1};
                end


                data = app.table.Data;
                [linhas, ~] = size(data);


                for linha = 1:linhas

                    if ~ismember(str2double(data{linha,2}), dic_array) || ~ismember(str2double(data{linha,3}), dic_array)

                        choice = questdlg(['One or more pins on the loaded excel table are not present in the loaded dictionary. Continuing will eventually' ...
                            ' result in an error! Do you wish to proceed?'],...
                            'Pin Missing', ...
                            'OK','Cancel','Cancel');

                        if strcmp(choice, 'Cancel')
                            show_cells(app,app.read_excel_button)
                            log_message(app.status_window, "Operation interruped by user!")
                            return;  % or do something else, like stop processing
                        end

                        break;
                    end
                end


                %% read excell

                [linhas, ~] = size(data);
                for linha = 1:linhas

                    % read table values and update them
                    app.curve_label.Value = data{linha,1};
                    app.pin1.Value = data{linha,2};
                    app.pin2.Value = data{linha,3};
                    app.ds_comp.Value= data{linha,4};
                    app.gs_comp.Value= data{linha,5};
                    app.aperture.Value = data{linha,6};
                    app.vgs_min.Value= data{linha,7};
                    app.vgs_max.Value= data{linha,8};
                    app.vds.Value= data{linha,9};
                    app.n_points.Value= data{linha,10};
                    app.dwell.Value= data{linha,12};
                    app.dwellvoltage.Value = data{linha,13};
                    app.cycles.Value = data{linha,14};

                    % now just cick on the plot button!

                    plot_buttonButtonPushed(app, event);

                    if app.interrupt_flag ==1
                        app.interrupt_flag =0;
                        break
                    end

                end
            end
            show_cells(app,app.read_excel_button)
        end

        % Selection changed function: Y_axis
        function Y_axisSelectionChanged(app, event)
            update_axes(app)

        end

        % Selection changed function: X_axis
        function X_axisSelectionChanged(app, event)
            update_axes(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            %% get row
            selection = app.UITable.Selection;
            if selection~=0
                row = selection(1);
                app.UITable.Selection=[1,2];


                %% get row data

                label_data = app.UITable.Data;
                selected_label = label_data{row,1};

                % Find the line object with a specific DisplayName in the axes
                curveToToggle = findobj(app.UIAxes, 'Type', 'Line', 'DisplayName', selected_label);

                %disp(curveToToggle)
                % Check if the curve exists
                if ~isempty(curveToToggle)
                    % Get the current visibility state
                    currentVisibility = get(curveToToggle, 'Visible');

                    % Toggle visibility
                    if strcmp(currentVisibility, 'on')
                        set(curveToToggle, 'Visible', 'off');
                        grayStyle = uistyle('FontColor', [0.5 0.5 0.5]);
                        addStyle(app.UITable, grayStyle, 'cell', [row, 1]);
                    else
                        set(curveToToggle, 'Visible', 'on');
                        blackStyle = uistyle('FontColor', [0 0 0]);
                        addStyle(app.UITable, blackStyle, 'cell', [row, 1]);
                    end
                else

                end
            end


        end

        % Selection changed function: UITablevi
        function UITableviSelectionChanged(app, event)
            %% get row
            selection = app.UITablevi.Selection;
            if selection ~=0
                row = selection(1);
                app.UITablevi.Selection=[1,2];
                %% get row data

                label_data = app.UITablevi.Data;
                selected_label = label_data{row,1};

                % Find the line object with a specific DisplayName in the axes
                curveToToggle = findobj(app.UIAxes_vi, 'Type', 'Line', 'DisplayName', selected_label);

                % Check if the curve exists
                if ~isempty(curveToToggle)
                    % Get the current visibility state
                    currentVisibility = get(curveToToggle, 'Visible');

                    % Toggle visibility
                    if strcmp(currentVisibility, 'on')
                        set(curveToToggle, 'Visible', 'off');
                        grayStyle = uistyle('FontColor', [0.5 0.5 0.5]);
                        addStyle(app.UITablevi, grayStyle, 'cell', [row, 1]);
                    else
                        set(curveToToggle, 'Visible', 'on');
                        blackStyle = uistyle('FontColor', [0 0 0]);
                        addStyle(app.UITablevi, blackStyle, 'cell', [row, 1]);
                    end
                else

                end
            end

        end

        % Button pushed function: BREAKButton
        function BREAKButtonPushed(app, event)
            app.interrupt_flag =1;
            log_message(app.status_window, "Operation interrupted by the user!")
            show_cells(app,app.cells_to_hide)
        end

        % Button pushed function: USENAMEASBACKUPButton
        function USENAMEASBACKUPButtonPushed(app, event)
            app.file_name.Value = app.hard_save_file_name.Value;
        end

        % Button pushed function: ImportDictionaryButton
        function ImportDictionaryButtonPushed(app, event)


            % Load table data from Excel file
            log_message(app.status_window, "Loading dictionary...")
            fileName = uigetfile( ...
                {'*.csv;*.xls;*.xlsx', 'CSV and Excel Files (*.csv, *.xls, *.xlsx)'}, ...
                'Select a Data File',fileparts(mfilename('fullpath')));

            % forces the app to keep on top
            drawnow;figure(app.UIFigure);

            if fileName==0
                log_message(app.status_window, "No file selected!")
                return;
            end

            app.dict_name.Value = fileName;
            app.dict_table.Data = table2cell(load_table(fileName)); % Convert table to cell array for display

            log_message(app.status_window, "Loaded " + fileName +" !")


        end

        % Button pushed function: UseDefaultDictionaryButton
        function UseDefaultDictionaryButtonPushed(app, event)
            tab = table ((1:16)',(1:16)');
            app.dict_table.Data =table2cell(tab);
            log_message(app.status_window,"Default dictionary loaded!")
        end

        % Value changed function: vi_switch
        function vi_switchValueChanged(app, event)
            value = app.vi_switch.Value;
            if value == "vDS"

                %% show resistance label
                app.add_resistance.Visible = "on";
                %%
                old_vgs_comp = app.gs_comp.Value;
                old_vds_comp = app.ds_comp.Value;

                app.gs_comp.Value = old_vds_comp;
                app.ds_comp.Value = old_vgs_comp;
                % Update labels for VI mode
                app.vGSmaxVEditFieldLabel.Text = sprintf('vDS max\n(V)');
                app.vGSminVEditFieldLabel.Text = sprintf('vDS min\n(V)');
                app.vDSVEditFieldLabel.Text = sprintf('vGS\n(V)');

                %                 % Adjust radio buttons and disable them
                %                 app.X_axis.SelectedObject = app.X_axis_vds;
                %                 app.Y_axis.SelectedObject = app.Y_axis_ids;
                %                 app.X_axis.Enable = "Off";
                %                 app.Y_axis.Enable = "Off";

                % Hide normal axis and its content
                app.UIAxes.Visible = "Off"; % Hide the axis
                delete(axtoolbar(app.UIAxes));
                set(findall(app.UIAxes, '-property', 'Visible'), 'Visible', 'Off'); % Hide all children

                % Show VI axis and its content
                app.UIAxes_vi.Visible = "On"; % Show the axis
                axtoolbar(app.UIAxes_vi, 'default');

                set(findall(app.UIAxes_vi, '-property', 'Visible'), 'Visible', 'On'); % Show all children

                % Show curve labels for VI mode
                app.UITablevi.Visible = "on";

                % Hide curve labels for normal mode
                app.UITable.Visible = "off";

            else
                %% hide resistance to label
                app.add_resistance.Visible = "off";

                %%
                old_vgs_comp = app.gs_comp.Value;
                old_vds_comp = app.ds_comp.Value;

                app.gs_comp.Value = old_vds_comp;
                app.ds_comp.Value = old_vgs_comp;
                % Update labels for normal mode
                app.vGSmaxVEditFieldLabel.Text = sprintf('vGS max\n(V)');
                app.vGSminVEditFieldLabel.Text = sprintf('vGS min\n(V)');
                app.vDSVEditFieldLabel.Text = sprintf('vDS\n(V)');

                % Adjust radio buttons and enable them
                app.X_axis.SelectedObject = app.X_axis_vgs;
                app.Y_axis.SelectedObject = app.Y_axis_ids;
                app.X_axis.Enable = "On";
                app.Y_axis.Enable = "On";

                % Hide VI axis and its content
                app.UIAxes_vi.Visible = "Off"; % Hide the axis
                delete(axtoolbar(app.UIAxes_vi));
                set(findall(app.UIAxes_vi, '-property', 'Visible'), 'Visible', 'Off'); % Hide all children


                % Show normal axis and its content
                app.UIAxes.Visible = "On"; % Show the axis
                axtoolbar(app.UIAxes, 'default');

                set(findall(app.UIAxes, '-property', 'Visible'), 'Visible', 'On'); % Show all children

                % Show curve labels for normal mode
                app.UITablevi.Visible= "off";

                % Hide curve labels for VI mode
                app.UITable.Visible= "on";
            end


        end

        % Button pushed function: CHOOSEPATHButton
        function CHOOSEPATHButtonPushed(app, event)
            %selected_path = uigetdir(pwd, 'Select Folder');


            % Choose dir
            log_message(app.status_window, "Choosing directory..")
            selected_path = uigetdir(pwd, 'Select Folder');


            % forces the app to keep on top
            drawnow;figure(app.UIFigure);

            if selected_path==0
                log_message(app.status_window, "No folder selected!")
                return;
            end
            app.save_path.Value = selected_path;

        end

        % Button pushed function: SHOWHIDEALLButton
        function SHOWHIDEALLButtonPushed(app, event)

            if app.hide_show_but_flag==0
                app.hide_show_but_flag=1;
                visibility = "off";
            else
                app.hide_show_but_flag=0;
                visibility = "on";
            end

            if app.vi_switch.Value =="vDS"
                hide_show_curves(app,app.UITablevi,app.UIAxes_vi,visibility)
            else
                hide_show_curves(app,app.UITable,app.UIAxes,visibility)
            end


        end

        % Value changed function: curve_label
        function curve_labelValueChanged(app, event)
            value = app.curve_label.Value;
            % Replace parentheses with square brackets
            value = strrep(value, '(', '[');
            value = strrep(value, ')', ']');

            % Set the cleaned-up value back to the field
            app.curve_label.Value = value;
        end

        % Value changed function: file_name
        function file_nameValueChanged(app, event)
            value = app.file_name.Value;

            % Forbidden characters (Windows restrictions)
            forbidden = ['\', '/', ':', '*', '?', '"', '<', '>', '|'];

            % Check if value contains any forbidden characters
            if any(ismember(value, forbidden))

                choice = questdlg( ...
                    'File name contains forbidden characters: \ / : * ? " < > |. Code might break.', ...
                    'Invalid File Name', ...
                    'Got it, I''ll fix it!', ...
                    'I choose... death!', ...
                    'Got it, I''ll fix it!');

                if strcmp(choice, 'Got it, I''ll fix it!')
                    app.file_name.Value = '';  % Clear it
                else
                    % Keep the value as-is (or revert if you saved a previous one)
                    app.file_name.Value = value;
                end
            end


        end

        % Value changed function: hard_save_file_name
        function hard_save_file_nameValueChanged(app, event)
            value = app.hard_save_file_name.Value;

            % Forbidden characters (Windows restrictions)
            forbidden = ['\', '/', ':', '*', '?', '"', '<', '>', '|'];

            % Check if value contains any forbidden characters
            if any(ismember(value, forbidden))

                choice = questdlg( ...
                    'File name contains forbidden characters: \ / : * ? " < > |. Code might break.', ...
                    'Invalid File Name', ...
                    'Got it, I''ll fix it!', ...
                    'I choose... death!', ...
                    'Got it, I''ll fix it!');

                if strcmp(choice, 'Got it, I''ll fix it!')
                    app.file_name.Value = '';  % Clear it
                else
                    % Keep the value as-is (or revert if you saved a previous one)
                    app.file_name.Value = value;
                end
            end
        end

        % Value changed function: add_resistance
        function add_resistanceValueChanged(app, event)
            value = app.add_resistance.Value;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {739, 739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {445, '1x', 786};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.PowerPanel = uipanel(app.GridLayout3);
            app.smu_lamp = uilamp(app.PowerPanel);
            app.box_lamp = uilamp(app.PowerPanel);
            app.BOXSwitchLabel = uilabel(app.PowerPanel);
            app.BOXSwitchLabel.Text = 'BOX';
            app.box_switch = uiswitch(app.PowerPanel, 'toggle');
            app.box_switch.ValueChangedFcn = createCallbackFcn(app, @box_switchValueChanged, true);
            app.SMUSwitchLabel = uilabel(app.PowerPanel);
            app.SMUSwitchLabel.Text = 'SMU';
            app.smu_switch = uiswitch(app.PowerPanel, 'toggle');
            app.smu_switch.ValueChangedFcn = createCallbackFcn(app, @smu_switchValueChanged, true);
            app.status_window = uitextarea(app.GridLayout3);
            app.table = uitable(app.GridLayout3);
            app.table.ColumnName = {'curve_id'; 'pin_1'; 'pin_2'; 'DS_comp(A)'; 'GS_comp(A)'; 'vGS_min(V)'; 'vGS_max(V)'; 'vDS(V)'; 'n_points'; 'step_size'; 'dwell_time'; 'reverse'; 'cycles'};
            app.table.RowName = {};
            app.table.SelectionType = 'column';
            app.table.ColumnEditable = true;
            app.load_excel_button = uibutton(app.GridLayout3, 'push');
            app.load_excel_button.ButtonPushedFcn = createCallbackFcn(app, @load_excel_buttonButtonPushed, true);
            app.load_excel_button.Text = 'Load excel';
            app.read_excel_button = uibutton(app.GridLayout3, 'push');
            app.read_excel_button.ButtonPushedFcn = createCallbackFcn(app, @read_excel_buttonButtonPushed, true);
            app.read_excel_button.Text = {'Start programmed'; 'measurement'};
            app.exel_name = uieditfield(app.GridLayout3, 'text');
            app.exel_name.Editable = 'off';
            app.exel_name.Value = '_.xlsx';
            app.BREAKButton = uibutton(app.GridLayout3, 'push');
            app.BREAKButton.ButtonPushedFcn = createCallbackFcn(app, @BREAKButtonPushed, true);
            app.BREAKButton.Text = 'BREAK';
            app.DictimporterPanel = uipanel(app.GridLayout3);
            app.DictimporterPanel.Title = 'Dict importer';
            app.dict_table = uitable(app.DictimporterPanel);
            app.dict_table.ColumnName = {'Chip'; 'box'};
            app.dict_table.RowName = {};
            app.dict_table.ColumnEditable = true;
            app.ImportDictionaryButton = uibutton(app.DictimporterPanel, 'push');
            app.ImportDictionaryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDictionaryButtonPushed, true);
            app.ImportDictionaryButton.Text = 'Import Dictionary';
            app.dict_name = uieditfield(app.DictimporterPanel, 'text');
            app.dict_name.Editable = 'off';
            app.dict_name.Value = '_.xlsx';
            app.UseDefaultDictionaryButton = uibutton(app.DictimporterPanel, 'push');
            app.UseDefaultDictionaryButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultDictionaryButtonPushed, true);
            app.UseDefaultDictionaryButton.Text = {'Use Default'; 'Dictionary'};
            app.PortSelectorPanel = uipanel(app.GridLayout3);
            app.PortSelectorPanel.Title = 'Port Selector';
            app.GridLayout5 = uigridlayout(app.PortSelectorPanel);
            app.Arduino1PortLabel = uilabel(app.GridLayout5);
            app.Arduino1PortLabel.Text = {'Arduino 1 Port'; '(pins 1 - 8)'};
            app.Arduino1Port = uieditfield(app.GridLayout5, 'text');
            app.Arduino1Port.Value = 'COM3';
            app.Arduino2Portpins916Label = uilabel(app.GridLayout5);
            app.Arduino2Portpins916Label.Text = {'Arduino 2 Port'; '(pins 9 - 16)'};
            app.Arduino2Port = uieditfield(app.GridLayout5, 'text');
            app.Arduino2Port.Value = 'COM4';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'V2.2';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.CenterPanel);
            app.DScomplianceLabel = uilabel(app.GridLayout4);
            app.DScomplianceLabel.Text = {'DS '; 'compliance'; '(A)'};
            app.ds_comp = uieditfield(app.GridLayout4, 'numeric');
            app.ds_comp.Value = 0.01;
            app.GScomplianceAEditFieldLabel = uilabel(app.GridLayout4);
            app.GScomplianceAEditFieldLabel.Text = {'GS'; 'compliance'; '(A)'};
            app.gs_comp = uieditfield(app.GridLayout4, 'numeric');
            app.gs_comp.Value = 1e-06;
            app.vGSminVEditFieldLabel = uilabel(app.GridLayout4);
            app.vGSminVEditFieldLabel.Text = {'vGS min'; '(V)'};
            app.vgs_min = uieditfield(app.GridLayout4, 'numeric');
            app.vgs_min.Value = -0.8;
            app.vGSmaxVEditFieldLabel = uilabel(app.GridLayout4);
            app.vGSmaxVEditFieldLabel.Text = {'vGS max'; '(V)'};
            app.vgs_max = uieditfield(app.GridLayout4, 'numeric');
            app.vgs_max.Value = 1.2;
            app.vDSVEditFieldLabel = uilabel(app.GridLayout4);
            app.vDSVEditFieldLabel.Text = {'vDS'; '(V)'};
            app.vds = uieditfield(app.GridLayout4, 'numeric');
            app.vds.Value = 0.01;
            app.numberpointsEditFieldLabel = uilabel(app.GridLayout4);
            app.numberpointsEditFieldLabel.Text = {'Number of'; 'points'};
            app.n_points = uieditfield(app.GridLayout4, 'numeric');
            app.n_points.ValueChangedFcn = createCallbackFcn(app, @n_pointsValueChanged, true);
            app.n_points.Value = 40;
            app.DwelltimeEditFieldLabel = uilabel(app.GridLayout4);
            app.DwelltimeEditFieldLabel.Text = {'Dwell '; 'time'};
            app.dwell = uieditfield(app.GridLayout4, 'numeric');
            app.dwell.Value = 1;
            app.stepEditFieldLabel = uilabel(app.GridLayout4);
            app.stepEditFieldLabel.Text = 'Step';
            app.step = uieditfield(app.GridLayout4, 'numeric');
            app.step.ValueChangedFcn = createCallbackFcn(app, @stepValueChanged, true);
            app.SweepdirectionButtonGroup = uibuttongroup(app.GridLayout4);
            app.SweepdirectionButtonGroup.Title = 'Sweep direction';
            app.increase_button = uiradiobutton(app.SweepdirectionButtonGroup);
            app.increase_button.Text = 'Increase';
            app.increase_button.Value = true;
            app.decrease_button = uiradiobutton(app.SweepdirectionButtonGroup);
            app.decrease_button.Text = 'Decrease';
            app.cycles = uieditfield(app.GridLayout4, 'numeric');
            app.stepEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.stepEditFieldLabel_2.Text = {'Number of'; 'cycles'};
            app.DwellvoltageEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.DwellvoltageEditFieldLabel_2.Text = {'Dwell '; 'voltage'};
            app.dwellvoltage = uieditfield(app.GridLayout4, 'numeric');
            app.TimeofLastAcquisitionLabel = uilabel(app.GridLayout4);
            app.TimeofLastAcquisitionLabel.Text = {'Time of Last'; 'Acquisition'};
            app.curve_time = uieditfield(app.GridLayout4, 'numeric');
            app.curve_time.Editable = 'off';
            app.SweepvariableSwitchLabel = uilabel(app.GridLayout4);
            app.SweepvariableSwitchLabel.Text = 'Sweep variable';
            app.vi_switch = uiswitch(app.GridLayout4, 'slider');
            app.vi_switch.Items = {'vGS', 'vDS'};
            app.vi_switch.ValueChangedFcn = createCallbackFcn(app, @vi_switchValueChanged, true);
            app.vi_switch.Value = 'vGS';
            app.GScomplianceAEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.GScomplianceAEditFieldLabel_2.Text = {'Aperture'; '[0.01 : 10]'; ''};
            app.aperture = uieditfield(app.GridLayout4, 'numeric');
            app.aperture.Value = 1;
            app.CompliancebreakSwitchLabel = uilabel(app.GridLayout4);
            app.CompliancebreakSwitchLabel.Text = {'Compliance'; 'break'};
            app.CompliancebreakSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_vi = uiaxes(app.GridLayout2);
            title(app.UIAxes_vi, 'Title vi')
            xlabel(app.UIAxes_vi, 'X')
            ylabel(app.UIAxes_vi, 'Y')
            app.plot_button = uibutton(app.GridLayout2, 'push');
            app.plot_button.ButtonPushedFcn = createCallbackFcn(app, @plot_buttonButtonPushed, true);
            app.plot_button.Text = 'MEASURE';
            app.DELETEHIDDENButton = uibutton(app.GridLayout2, 'push');
            app.DELETEHIDDENButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEHIDDENButtonPushed, true);
            app.DELETEHIDDENButton.Text = {'DELETE'; 'HIDDEN'};
            app.curve_label = uieditfield(app.GridLayout2, 'text');
            app.curve_label.ValueChangedFcn = createCallbackFcn(app, @curve_labelValueChanged, true);
            app.curve_label.Value = 'Curve_label';
            app.SAVEButton = uibutton(app.GridLayout2, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.hard_save_file_name = uieditfield(app.GridLayout2, 'text');
            app.hard_save_file_name.ValueChangedFcn = createCallbackFcn(app, @hard_save_file_nameValueChanged, true);
            app.hard_save_file_name.Value = 'FileName';
            app.pin2 = uieditfield(app.GridLayout2, 'text');
            app.pin2.Value = '2';
            app.pin1 = uieditfield(app.GridLayout2, 'text');
            app.pin1.Value = '1';
            app.file_name = uieditfield(app.GridLayout2, 'text');
            app.file_name.ValueChangedFcn = createCallbackFcn(app, @file_nameValueChanged, true);
            app.file_name.Value = 'BackupFileName';
            app.delete_button = uibutton(app.GridLayout2, 'push');
            app.delete_button.ButtonPushedFcn = createCallbackFcn(app, @delete_buttonPushed, true);
            app.delete_button.Text = {'DELETE'; 'ALL'};
            app.Y_axis = uibuttongroup(app.GridLayout2);
            app.Y_axis.SelectionChangedFcn = createCallbackFcn(app, @Y_axisSelectionChanged, true);
            app.Y_axis.Title = 'Y';
            app.Y_axis_vgs = uiradiobutton(app.Y_axis);
            app.Y_axis_vgs.Text = 'Vgs';
            app.Y_axis_igs = uiradiobutton(app.Y_axis);
            app.Y_axis_igs.Text = 'Igs';
            app.Y_axis_vds = uiradiobutton(app.Y_axis);
            app.Y_axis_vds.Text = 'Vds';
            app.Y_axis_ids = uiradiobutton(app.Y_axis);
            app.Y_axis_ids.Text = 'Ids';
            app.Y_axis_ids.Value = true;
            app.Y_axis_t = uiradiobutton(app.Y_axis);
            app.Y_axis_t.Text = 't';
            app.X_axis = uibuttongroup(app.GridLayout2);
            app.X_axis.SelectionChangedFcn = createCallbackFcn(app, @X_axisSelectionChanged, true);
            app.X_axis.Title = 'X';
            app.X_axis_vgs = uiradiobutton(app.X_axis);
            app.X_axis_vgs.Text = 'Vgs';
            app.X_axis_vgs.Value = true;
            app.X_axis_igs = uiradiobutton(app.X_axis);
            app.X_axis_igs.Text = 'Igs';
            app.X_axis_vds = uiradiobutton(app.X_axis);
            app.X_axis_vds.Text = 'Vds';
            app.X_axis_ids = uiradiobutton(app.X_axis);
            app.X_axis_ids.Text = 'Ids';
            app.X_axis_t = uiradiobutton(app.X_axis);
            app.X_axis_t.Text = 't';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'CurveId'; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITablevi = uitable(app.GridLayout2);
            app.UITablevi.ColumnName = {'CurveId'; ''};
            app.UITablevi.RowName = {};
            app.UITablevi.SelectionChangedFcn = createCallbackFcn(app, @UITableviSelectionChanged, true);
            app.USENAMEASBACKUPButton = uibutton(app.GridLayout2, 'push');
            app.USENAMEASBACKUPButton.ButtonPushedFcn = createCallbackFcn(app, @USENAMEASBACKUPButtonPushed, true);
            app.USENAMEASBACKUPButton.Text = {'USE NAME'; 'AS BACKUP'};
            app.save_path = uieditfield(app.GridLayout2, 'text');
            app.save_path.Editable = 'off';
            app.save_path.Value = 'C:\Users\diogo\Desktop\new_tese_after_git\eletronica\SMU\my_stuff\final_GUI\GUI-f-data-analysis';
            app.CHOOSEPATHButton = uibutton(app.GridLayout2, 'push');
            app.CHOOSEPATHButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEPATHButtonPushed, true);
            app.CHOOSEPATHButton.Text = 'CHOOSE PATH';
            app.SHOWHIDEALLButton = uibutton(app.GridLayout2, 'push');
            app.SHOWHIDEALLButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWHIDEALLButtonPushed, true);
            app.SHOWHIDEALLButton.Text = {'SHOW/ HIDE'; 'ALL'};
            app.AddPins = uicheckbox(app.GridLayout2);
            app.AddPins.Text = {'Add pins '; 'to label'};
            app.SelectpinpairLabel = uilabel(app.GridLayout2);
            app.SelectpinpairLabel.Text = 'Select pin pair:';
            app.add_resistance = uicheckbox(app.GridLayout2);
            app.add_resistance.ValueChangedFcn = createCallbackFcn(app, @add_resistanceValueChanged, true);
            app.add_resistance.Visible = 'off';
            app.add_resistance.Text = {'Add resistance'; 'to label'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end